classdef aplicacionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ArchivoMenu                     matlab.ui.container.Menu
        AbrirArchivoMenu                matlab.ui.container.Menu
        GuardarMenu                     matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        OperacionesBsicasMenu           matlab.ui.container.Menu
        EcualizacinMenu                 matlab.ui.container.Menu
        InversinMenu                    matlab.ui.container.Menu
        BinariaMenu                     matlab.ui.container.Menu
        FotogrficaMenu                  matlab.ui.container.Menu
        AdicinMenu                      matlab.ui.container.Menu
        SustraccinMenu                  matlab.ui.container.Menu
        EspejoMenu                      matlab.ui.container.Menu
        FiltrosMenu                     matlab.ui.container.Menu
        OrdenEstadsticosMenu            matlab.ui.container.Menu
        ModaMenu                        matlab.ui.container.Menu
        MediaMenu                       matlab.ui.container.Menu
        MedianaMenu                     matlab.ui.container.Menu
        GaussianoMenu                   matlab.ui.container.Menu
        MximosyMnimosMenu               matlab.ui.container.Menu
        LaplacianoMenu                  matlab.ui.container.Menu
        VecinosMenu                     matlab.ui.container.Menu
        VecinosMenu_2                   matlab.ui.container.Menu
        DeteccindecontornosMenu         matlab.ui.container.Menu
        PrewittMenu                     matlab.ui.container.Menu
        SobelMenu                       matlab.ui.container.Menu
        RobertsMenu                     matlab.ui.container.Menu
        OperacionesMorfologicasMenu     matlab.ui.container.Menu
        ErosionMenu                     matlab.ui.container.Menu
        DilatacinMenu                   matlab.ui.container.Menu
        ModificaciondelColordeOjosMenu  matlab.ui.container.Menu
        SegmentacionparaNRegionesMenu   matlab.ui.container.Menu
        CollageMenu                     matlab.ui.container.Menu
        EditarMenu                      matlab.ui.container.Menu
        AyudaMenu                       matlab.ui.container.Menu
        SalirMenu                       matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        RotarIzquierda                  matlab.ui.container.toolbar.PushTool
        RotarDerecha                    matlab.ui.container.toolbar.PushTool
        Deshacer                        matlab.ui.container.toolbar.PushTool
        TabGroup                        matlab.ui.container.TabGroup
        EdicinprincipalTab              matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ConvertirDropDown               matlab.ui.control.DropDown
        ConvertirDropDownLabel          matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        GeneracindecollageTab           matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        %Declarar las matrices de la imagen original
        imgOri = []
        mapOri = []
        typeOri = []

            %Declarar las matrices de la imagen procesada
            imgPro = []
            mapPro = []
            typePro = []

            %Declarar las funciones que va a deshacer
            imgDes = []
            mapDes = []
            typeDes = []
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: SalirMenu
        function Salir(app, event)
          SalirApp();
        end

        % Menu selected function: AbrirArchivoMenu
        function AbrirArchivo(app, event)
            %Llamar la funcion abrir imagen
            [img, map, type] = AbrirImagen();
            if isempty(img)
                return
            end

            % Almacenar y mostrar imagen
            app.imgOri  = img;
            app.mapOri  = map;
            app.typeOri = type;
                if app.typeOri == "indexed"
                    imshow(app.imgOri, app.mapOri, 'Parent', app.UIAxes);
                else
                    imshow(app.imgOri, 'Parent', app.UIAxes);

                end
            
            %Crear copia de las modificaciones
            app.imgPro  = app.imgOri;
            app.mapPro  = app.mapOri;
            app.typePro = app.typeOri;
                if app.typePro == "indexed"
                    imshow(app.imgPro, app.mapPro, 'Parent', app.UIAxes2);
                else
                    imshow(app.imgPro, 'Parent', app.UIAxes2);
                end

            % Pasar la variable que va a deshacer
            app.imgDes  = app.imgOri;
            app.mapDes  = app.mapOri;
            app.typeDes = app.typeOri;
            
        end

        % Menu selected function: GuardarMenu
        function GuardarMenuSelected(app, event)
            %Guardar la imagen procesada
            Guardar(app.imgPro, app.mapPro, app.typePro);
        end

        % Value changed function: ConvertirDropDown
        function CambioDeFormato(app, event)
            if isempty(app.imgPro) || app.ConvertirDropDown.Value == "Seleccionar El Tipo"
                return;
            end
            
            % Conversion de la imagen
            [imgCon, mapCon, typeCon] = Convertir(app.ConvertirDropDown, app.imgPro, app.mapPro, app.typePro);

            if ~isequal(app.typePro, typeCon)
                
                    % Actualizar imágenes y mostrar
                    UpdateImagenes(app, imgCon, mapCon, typeCon);
            end

            function UpdateImagenes(app, img, map, type)
            %Actualiza las imagenes en la aplicación

                app.imgDes = app.imgPro;
                app.mapDes = app.mapPro;
                app.typeDes = app.typePro;

                app.imgPro  = img;
                app.mapPro  = map;
                app.typePro = type;

                switch type
                    case "indexed"
                    imshow(img, map, 'Parent', app.UIAxes2);
                    otherwise
                    imshow(app.imgPro, 'Parent', app.UIAxes2);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'prism');
            app.UIFigure.Name = 'MATLAB App';
            app.ArchivoMenu = uimenu(app.UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.AbrirArchivoMenu = uimenu(app.ArchivoMenu);
            app.AbrirArchivoMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirArchivo, true);
            app.AbrirArchivoMenu.Text = 'Abrir Archivo';
            app.GuardarMenu = uimenu(app.ArchivoMenu);
            app.GuardarMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarMenuSelected, true);
            app.GuardarMenu.Text = 'Guardar';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OperacionesBsicasMenu = uimenu(app.Menu);
            app.OperacionesBsicasMenu.Text = 'Operaciones Básicas';
            app.EcualizacinMenu = uimenu(app.OperacionesBsicasMenu);
            app.EcualizacinMenu.Text = 'Ecualización';
            app.InversinMenu = uimenu(app.OperacionesBsicasMenu);
            app.InversinMenu.Text = 'Inversión';
            app.BinariaMenu = uimenu(app.InversinMenu);
            app.BinariaMenu.Text = 'Binaria';
            app.FotogrficaMenu = uimenu(app.InversinMenu);
            app.FotogrficaMenu.Text = 'Fotográfica';
            app.AdicinMenu = uimenu(app.OperacionesBsicasMenu);
            app.AdicinMenu.Text = 'Adición';
            app.SustraccinMenu = uimenu(app.OperacionesBsicasMenu);
            app.SustraccinMenu.Text = 'Sustracción';
            app.EspejoMenu = uimenu(app.Menu);
            app.EspejoMenu.Text = 'Espejo';
            app.FiltrosMenu = uimenu(app.Menu);
            app.FiltrosMenu.Text = 'Filtros';
            app.OrdenEstadsticosMenu = uimenu(app.FiltrosMenu);
            app.OrdenEstadsticosMenu.Text = 'Orden Estadísticos';
            app.ModaMenu = uimenu(app.OrdenEstadsticosMenu);
            app.ModaMenu.Text = 'Moda';
            app.MediaMenu = uimenu(app.OrdenEstadsticosMenu);
            app.MediaMenu.Text = 'Media';
            app.MedianaMenu = uimenu(app.OrdenEstadsticosMenu);
            app.MedianaMenu.Text = 'Mediana';
            app.GaussianoMenu = uimenu(app.FiltrosMenu);
            app.GaussianoMenu.Text = 'Gaussiano';
            app.MximosyMnimosMenu = uimenu(app.FiltrosMenu);
            app.MximosyMnimosMenu.Text = 'Máximos y Mínimos';
            app.LaplacianoMenu = uimenu(app.FiltrosMenu);
            app.LaplacianoMenu.Text = 'Laplaciano';
            app.VecinosMenu = uimenu(app.LaplacianoMenu);
            app.VecinosMenu.Text = '4 Vecinos';
            app.VecinosMenu_2 = uimenu(app.LaplacianoMenu);
            app.VecinosMenu_2.Text = '8 Vecinos';
            app.DeteccindecontornosMenu = uimenu(app.FiltrosMenu);
            app.DeteccindecontornosMenu.Text = 'Detección de contornos';
            app.PrewittMenu = uimenu(app.DeteccindecontornosMenu);
            app.PrewittMenu.Text = 'Prewitt';
            app.SobelMenu = uimenu(app.DeteccindecontornosMenu);
            app.SobelMenu.Text = 'Sobel';
            app.RobertsMenu = uimenu(app.DeteccindecontornosMenu);
            app.RobertsMenu.Text = 'Roberts';
            app.OperacionesMorfologicasMenu = uimenu(app.Menu);
            app.OperacionesMorfologicasMenu.Text = 'Operaciones Morfologicas';
            app.ErosionMenu = uimenu(app.OperacionesMorfologicasMenu);
            app.ErosionMenu.Text = 'Erosion';
            app.DilatacinMenu = uimenu(app.OperacionesMorfologicasMenu);
            app.DilatacinMenu.Text = 'Dilatación';
            app.ModificaciondelColordeOjosMenu = uimenu(app.Menu);
            app.ModificaciondelColordeOjosMenu.Text = 'Modificacion del Color de Ojos';
            app.SegmentacionparaNRegionesMenu = uimenu(app.Menu);
            app.SegmentacionparaNRegionesMenu.Text = 'Segmentacion para N Regiones';
            app.CollageMenu = uimenu(app.Menu);
            app.CollageMenu.Text = 'Collage';
            app.EditarMenu = uimenu(app.UIFigure);
            app.EditarMenu.Text = 'Editar';
            app.AyudaMenu = uimenu(app.UIFigure);
            app.AyudaMenu.Text = 'Ayuda';
            app.SalirMenu = uimenu(app.UIFigure);
            app.SalirMenu.MenuSelectedFcn = createCallbackFcn(app, @Salir, true);
            app.SalirMenu.Text = 'Salir';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.RotarIzquierda = uipushtool(app.Toolbar);
            app.RotarIzquierda.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Derecha.png');
            app.RotarDerecha = uipushtool(app.Toolbar);
            app.RotarDerecha.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Izquierda.png');
            app.Deshacer = uipushtool(app.Toolbar);
            app.Deshacer.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Deshacer.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EdicinprincipalTab = uitab(app.TabGroup);
            app.EdicinprincipalTab.Title = 'Edición principal';
            app.GridLayout = uigridlayout(app.EdicinprincipalTab);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Imagen Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'prism')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Imagen Procesada')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'prism')
            app.ConvertirDropDownLabel = uilabel(app.GridLayout);
            app.ConvertirDropDownLabel.Text = 'Convertir:';
            app.ConvertirDropDown = uidropdown(app.GridLayout);
            app.ConvertirDropDown.Items = {'Selecciona El Tipo', 'Binaria', 'Escala de Grises', 'RGB', 'Indexada'};
            app.ConvertirDropDown.ValueChangedFcn = createCallbackFcn(app, @CambioDeFormato, true);
            app.ConvertirDropDown.Value = 'Selecciona El Tipo';
            app.GeneracindecollageTab = uitab(app.TabGroup);
            app.GeneracindecollageTab.Title = 'Generación de collage';
            app.GridLayout2 = uigridlayout(app.GeneracindecollageTab);
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Imagen 1')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'Imagen 2')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout2);
            title(app.UIAxes5, 'Imagen 3')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout2);
            title(app.UIAxes6, 'Imagen 4')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.GridLayout2);
            title(app.UIAxes7, 'Imagen 5')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.GridLayout2);
            title(app.UIAxes8, 'Imagen 6')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.GridLayout2);
            title(app.UIAxes9, 'Imagen 7')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.GridLayout2);
            title(app.UIAxes10, 'Imagen 8')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes11 = uiaxes(app.GridLayout2);
            title(app.UIAxes11, 'Resultado collage')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplicacionGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end